classdef DesignSpace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DesignspaceviewerLabel     matlab.ui.control.Label
        VariableListBox            matlab.ui.control.ListBox
        ExperimentListBox          matlab.ui.control.ListBox
        ExperimentLabel            matlab.ui.control.Label
        VariableLabel              matlab.ui.control.Label
        LoadfileButton             matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        ToggledebugCheckBox        matlab.ui.control.CheckBox
        ColourpalletDropDownLabel  matlab.ui.control.Label
        ColourpalletDropDown       matlab.ui.control.DropDown
        DefaultcolorDropDownLabel  matlab.ui.control.Label
        DefaultcolorDropDown       matlab.ui.control.DropDown
        LinestyleDropDownLabel     matlab.ui.control.Label
        LinestyleDropDown          matlab.ui.control.DropDown
        InvertToggle               matlab.ui.control.CheckBox
        GraphAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filePath % File path
        fileName % File name
        variables = zeros(1) % The table where all the data gets stored
        varNames % The variable names
        experiments % The name of each experiment (numbered list)
        colorScheme = "autumn"; % Default colour map
        defaultColor = "blue"; % Default normal colour
        lineStyle = "-"; % Default line style
        
        
    end
    
    methods (Access = private)

%--------------------------------------------------------------------------
        
        % Resets the variables when a new file is loaded
        function reset(app)
            app.filePath = "";
            app.fileName = "";
            app.variables = zeros(1);
            app.varNames = "";
            app.experiments = "";
        end

%--------------------------------------------------------------------------        
        
        % Loads the variables and experiments into the list boxes
        function loadVariables(app)
            % Extracts the variable names and adds them to the variables
            % list box (var names in the header of the file submitted)
            app.varNames = app.variables.Properties.VariableNames;
            app.VariableListBox.Items = app.varNames;
            
            % Labels each experiment detected (number of rows) from 1 to
            % the total number of experiments
            numbOfExp = size(app.variables,1);
            app.experiments = string(1:numbOfExp);
            app.ExperimentListBox.Items = app.experiments;
        end
 
%--------------------------------------------------------------------------        
        
       %Updates the graph UI 
       function updateGraph(app)
            
            % Gets the selected value that is contained in the list box
            selectedGraphNames = app.ExperimentListBox.Value;
            
            % If there are multiple selected, it turns
            % them into a cell. Therefore this detects this and reverts it
            % back to a strign array if multiple are selected. If only one
            % is detected it is a char. Therefore convert it to a string.
            if iscell(selectedGraphNames)
                selectedGraphNames = cellfun(@string, selectedGraphNames);
            else
                selectedGraphNames = string(selectedGraphNames);
            end 
            
            %Creates an intal empty logal array with all zero with all the
            %possible selections it could be
            logicArry = false(1,length(app.ExperimentListBox.Items));
            
            %Loops through each experiment selectd and finds the
            %location of it.
            for resp = 1:length(selectedGraphNames)
                
                % Finds the location of the experiment in the list 
                % of the allowed experiments and flips it so it adds
                % correctly to the logic array.
                temp = strcmp(string(1:length(app.experiments)),selectedGraphNames(resp));
                
                % Adds on this location. If == 1 then that responce
                % function is being used, if == 0 then it is not.
                logicArry = logicArry + temp;
            end
            
            % Clear graph
            cla(app.GraphAxes,'reset')
            
            % Gets the selected value that is contained in the list box
            selectedVars = app.VariableListBox.Value;
            
            % If there are multiple selected, it turns
            % them into a cell. Therefore this detects this and reverts it
            % back to a strign array if multiple are selected. If only one
            % is detected it is a char. Therefore convert it to a string.
            if iscell(selectedVars)
                varOrder = cellfun(@string, selectedVars);
            else
                varOrder = string(selectedVars);
            end
            
            
            % If more than 1 variable is selected (so a 2D or more plot
            % can be done)
            if length(varOrder) > 1 && length(selectedGraphNames) >= 1
                
                % If too many variables are selected and are added to varOrder
                % then only select the first 5 (plot will only go as
                % high as the 5th dimension)
                if length(varOrder) > 5
                    varOrder = varOrder(1:5);
                end
                
                % Logic array contains the true false data of each
                % graph that is selected. Where 0 = not seleected and 1
                % = selectd. This function converts this into a
                % numbered array and removes any 0's so that it can be
                % used to index the combined data table. For example if
                % logicArry = [0 0 1 0 1 0] then it would be this .* [1
                % 2 3 4 5 6] = [0 0 3 0 5 0] and then zeros removed to
                % give [3 5] so the table is indexed at 3 and 5.
                logicArry = logicArry.*(1:1:length(logicArry));
                logicArry(logicArry==0) = [];
                logicArry = logicArry'; % Makes sure it is in the right direction
                
                % Uses this logical array to index the specfic data
                extractedData = app.variables(logicArry, varOrder);
                 
                % Plots a 2D graph when the number of columns in
                % extracted data = 2;
                if size(extractedData,2) == 2
                    
                    % 2D Scatter graph
                    scatter(app.GraphAxes, extractedData{:,1},extractedData{:,2}, 20, app.defaultColor, 'filled')
                    
                    % As extractedData is a table it selects the VariableNames
                    % as the x and y label.
                    xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                    ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                    
                    % If debug mode is on, display the text
                    if app.ToggledebugCheckBox.Value == 1
                        text(app.GraphAxes, extractedData{:,1},extractedData{:,2}, " " + string(logicArry))
                    end
                    
                % Plots a 3D graph when the number of columns in
                % extracted data = 3;    
                elseif size(extractedData,2) == 3
                    
                    % 3D scatter graph
                    scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, 20, app.defaultColor, 'filled')
                    
                    grid(app.GraphAxes, "on")
                    
                    % Add to the same axis the lines that go down to
                    % the xy plane
                    hold (app.GraphAxes, 'on')
                    
                    % Set upper and lower bound of the z axis so that the
                    % lines drawn don't always go to z = 0 and show loads
                    % of blank space.
                    lowerBound = floor(min(extractedData{:,3})/5)*5;
                    
                    % loop through each element and add the line that
                    % goes down to the xy plane
                    for x = 1:size(extractedData,1)
                        plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [lowerBound, extractedData{x,3}], 'Color', app.defaultColor, 'LineStyle', app.lineStyle)
                    end
                    
                    % Stop appending to the same axis
                    hold (app.GraphAxes, 'off')
                    
                    % Add the graph labels based on the table col name
                    % values
                    xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                    ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                    zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                    
                    % Get the current Z axis UB limit and then set the new
                    % Z axis limits to minimise whitespace.
                    zaxis = zlim(app.GraphAxes);
                    zlim(app.GraphAxes, [lowerBound, zaxis(2)])
                    
                    % if debug mode is on, then plot the numbers next
                    % each point for easier identifcation
                    if app.ToggledebugCheckBox.Value == 1
                        text(app.GraphAxes, extractedData{:,1},extractedData{:,2}, extractedData{:,3}, " " + string(logicArry))
                    end
                
                % Plots a 4D graph when the number of columns in
                % extracted data = 4, using color as the 4th dimension 
                elseif size(extractedData,2) == 4
                    % 4D scatter graph where the colour is the 4th
                    % dimenson
                    scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, 20, extractedData{:,4}, "filled", "")
                    
                    % Displays the gird so the vales are easier to read
                    grid(app.GraphAxes, "on");
                    
                    % Set the colur scheme to app.colorScheme
                    cmap = colormap(app.GraphAxes,app.colorScheme);
                    % If the invert option is selected it inverts the
                    % colour scheme.
                    if app.InvertToggle.Value == 1
                        cmap = colormap(app.GraphAxes, flipud(cmap));
                    end
                    % Add the colour bar
                    cbar = colorbar(app.GraphAxes);
                    
                    % This sets up the colours for the dotted lines so
                    % that they match the scattered point colours. If
                    % all the values in extracted method are the same,
                    % then set it to the first color. If if is a
                    % difference between the values, use the max and
                    % min values to generate c, which is a matrix that
                    % spaces out the colours uniformly and in realtion
                    % to the colours of the scatter points.
                    if nnz(diff(extractedData{:,4})) == 0
                        c = ones(size(cmap,1));
                    else
                        c = round(1+(size(cmap,1)-1)*(extractedData{:,4} - min(extractedData{:,4}))/(max(extractedData{:,4})-min(extractedData{:,4})));
                    end
                    
                    % Add to the same axis the lines that go down to
                    % the xy plane
                    hold(app.GraphAxes, 'on');
                    
                    % Set upper and lower bound of the z axis so that the
                    % lines drawn don't always go to z = 0 and show loads
                    % of blank space.
                    lowerBound = floor(min(extractedData{:,3})/5)*5;
                    
                    % Based on the index/ color psotion (c) plots a
                    % dotted line that goes down from the point to the
                    % xy plane
                    for x = 1:size(extractedData,1)
                        plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [lowerBound extractedData{x,3}], "Color",cmap(c(x),:), 'LineStyle', app.lineStyle)
                    end
                    
                    % Stop appending to the same axis
                    hold (app.GraphAxes, 'off');
                    
                    % Add the graph labels based on the table col name
                    % values
                    xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                    ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                    zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                    
                    % Get the current Z axis UB limit and then set the new
                    % Z axis limits to minimise whitespace.
                    zaxis = zlim(app.GraphAxes);
                    zlim(app.GraphAxes, [lowerBound, zaxis(2)])
                    
                    % Add title to colour bar
                    title(cbar, extractedData.Properties.VariableNames(4), "FontSize",10, "FontWeight","bold")
                    % Puts in a title so that the spacing at the top of
                    % the graph includes the title above the cbar
                    title(app.GraphAxes, " ", "FontSize",20, "FontWeight","bold")
                    cbar.FontSize = 10; %Makes font of cbar increments larger

                    
                    % if debug mode is on, then plot the numbers next
                    % each point for easier identifcation
                    if app.ToggledebugCheckBox.Value == 1
                        
                        % Loops through each point and adds text next
                        % to it that includes the number and var4 data
                        for graph = 1:size(extractedData,1)
                            text(app.GraphAxes, extractedData{graph,1},extractedData{graph,2}, extractedData{graph,3}, " " + string(logicArry(graph)) + ", " + string(extractedData{graph,4}))
                        end

                    end
                
                % Plots a 5D graph when the number of columns in
                % extracted data = 5, using color as the 4th 
                % dimension and size of the dot as the 5th dimension.
                elseif size(extractedData,2) == 5
                    % Change these to vary the maximum and minimim dot
                    % sizes
                    maxDotSize = 100;
                    minDotSize = 5;
                    
                    % Scale the data so that the size is bewteen the
                    % max and min dot size, for when it is plotted on
                    % the graph.
                    scaledData = rescale(extractedData{:,5}, minDotSize, maxDotSize);
                    
                    % Generates 5 uniformly spaced marker sizes between
                    % the min and max dot size specified
                    ledgMarkerSize = linspace(minDotSize, maxDotSize, 5);
                    markersActualNumb = rescale(ledgMarkerSize, min(extractedData{:,5}), max(extractedData{:,5}));
                    
                    % Add to the same axis the lines that go down to
                    % the xy plane
                    hold (app.GraphAxes, 'on');
                    
                    % The loop starts to create the figger that
                    % displays the dot size. It works by plotting 5
                    % points of varying marker size (plot3 has to be
                    % used instead of scatter3 as only this changes the
                    % dot size in the legend for some reason) and
                    % setting their visabilty to 0. Then each each
                    % number that corrisponds to the 5 uniformly
                    % distrubuted sizes is set to a cell whoch then
                    % later is used to called the legend function
                    % (which only shows these first 5 dots). This has
                    % to be done before the scatter function as it
                    % needs to be the FIRST 5 dots.
                    
                    for ind = 1:numel(ledgMarkerSize)
                       % Generate the plots and save them in figplots 
                       % array. Set them at (0,0,0) and set the marker
                       % size to the square root of the ledgMarkerSize
                       % (for some reason this works well for size
                       % comparson, not sure how acurate it is).
                       figplots(ind) = plot3(app.GraphAxes,0,0,0,'bo','markersize',round(sqrt(ledgMarkerSize(ind)),1),'MarkerFaceColor','blue', "MarkerEdgeColor","blue");
                       set(figplots(ind),'visible','off') % Make them invisable
                       
                       % Adds each number (acutal number not the marker
                       % size number) so that it is displayed as the
                       % label when the legend function is called later
                       legentry{ind} = num2str(round(markersActualNumb(ind),1));
                    end
                    
                    % 5D scatter graph where the colour is the 4th
                    % dimenson and size of the dot is the 5th dimension
                    scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, scaledData, extractedData{:,4}, "filled")
                    
                    % Stop appending to the same axis
                    hold (app.GraphAxes, 'off');
                    
                    % Displays the gird so the vales are easier to read
                    grid(app.GraphAxes, "on")
                    
                    % Set the colur scheme to app.colorScheme
                    cmap = colormap(app.GraphAxes,app.colorScheme);
                    % If the invert option is selected it inverts the
                    % colour scheme.
                    if app.InvertToggle.Value == 1
                        cmap = colormap(app.GraphAxes, flipud(cmap));
                    end
                    
                    % Add the colour bar
                    cbar = colorbar(app.GraphAxes);
                    
                    % This sets up the colours for the dotted lines so
                    % that they match the scattered point colours. If
                    % all the values in extracted method are the same,
                    % then set it to the first color. If if is a
                    % difference between the values, use the max and
                    % min values to generate c, which is a matrix that
                    % spaces out the colours uniformly and in realtion
                    % to the colours of the scatter points.
                    if nnz(diff(extractedData{:,4})) == 0
                        c = ones(size(cmap,1));
                    else
                        c = round(1+(size(cmap,1)-1)*(extractedData{:,4} - min(extractedData{:,4}))/(max(extractedData{:,4})-min(extractedData{:,4})));
                    end

                    % Add to the same axis the lines that go down to
                    % the xy plane
                    hold (app.GraphAxes, 'on');
                    
                    % Set upper and lower bound of the z axis so that the
                    % lines drawn don't always go to z = 0 and show loads
                    % of blank space.
                    lowerBound = floor(min(extractedData{:,3})/5)*5;
                    
                    % Based on the index/ color psotion (c) plots a
                    % dotted line that goes down from the point to the
                    % xy plane
                    for x = 1:size(extractedData,1)
                        plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [lowerBound extractedData{x,3}], "Color",cmap(c(x),:), 'LineStyle', app.lineStyle)
                    end
                    
                    % Stop appending to the same axis
                    hold (app.GraphAxes, 'off');
                    
                    % Add the graph labels based on the table col name
                    % values
                    xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                    ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                    zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                    
                    % Add title to colour bar
                    title(cbar, extractedData.Properties.VariableNames(4), "FontSize",10, "FontWeight","bold")
                    
                    % Puts in a title so that the spacing at the top of
                    % the graph includes the title above the cbar
                    title(app.GraphAxes, " ", "FontSize",20, "FontWeight","bold")
                    cbar.FontSize = 10; %Makes font of cbar increments larger
                    
                    % Creat the legend at the end after all points have
                    % been added as we only want the first 5 points
                    % (the dummy invisable ones stored in figplots)
                    leg = legend(app.GraphAxes, legentry);
                    leg.Location = 'eastoutside'; %Display next to the colour bar
                    leg.FontSize = 10; % Make font the same size as the color bar
                    % Gives the legend a title
                    title(leg, extractedData.Properties.VariableNames(5),"FontSize",10, "FontWeight","bold");
                    
                    % Get the current Z axis UB limit and then set the new
                    % Z axis limits to minimise whitespace.
                    zaxis = zlim(app.GraphAxes);
                    zlim(app.GraphAxes, [lowerBound, zaxis(2)])
                    
                    %For some reason these actions set the graph state
                    %to 2D, so make sure it is 3D.
                    view(app.GraphAxes,3)

                    % if debug mode is on, then plot the numbers next
                    % each point for easier identifcation
                    if app.ToggledebugCheckBox.Value == 1
                        
                        % Loops through each point and adds text next
                        % to it that includes the number, var4 and 
                        % var5 data data
                        for graph = 1:size(extractedData,1)
                            text(app.GraphAxes, extractedData{graph,1},extractedData{graph,2}, extractedData{graph,3}, " " + string(logicArry(graph)) + ", " + string(extractedData{graph,4}) + ", " + string(extractedData{graph,5}))
                        end
                    end
                end
            end
       end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % All avaiable types of colour maps
            app.ColourpalletDropDown.Items = ["parula", "turbo", "hsv", ... 
                "hot", 	"cool", "spring", "summer", "autumn", "winter", ...
                "gray", "bone", "copper", "pink", "jet", "lines", ...
                "colorcube", "prism", "flag", "white"];
            
            % Predefined matlab colours
            app.DefaultcolorDropDown.Items = ["yellow", "magenta", "cyan", "red", "green", "blue", "white", "black"];
            
            % Predefined line types
            app.LinestyleDropDown.Items = ["none","-","--",":","-."];
            
            % Set defaults (defined when colorScheme etc were made)
            app.ColourpalletDropDown.Value = app.colorScheme;
            app.DefaultcolorDropDown.Value = app.defaultColor;
            app.LinestyleDropDown.Value = app.lineStyle;
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            % Resets all the variables
            app.reset();
            
            % Opens the file explorer to allow the user to select an excel
            % file - only files containing '.xls' will be shown.
            [app.fileName, app.filePath] = uigetfile('*.xls*', 'Open Excel File');
            
            % Predefine error check
            errCheck = true;
            % Start the error checking loop
            while errCheck
                
                % If no file is selected
                if app.fileName == 0
                    app.Label.Text = "No file was selected";
                    break % End the error checking loop
                end
                
                % Gets the extension of the file
                [~,~,extension] = fileparts(app.fileName);
                % If the extension does not contain '.xls'
                if ~contains(extension, '.xls')
                    app.Label.Text = "File type must be of the form *.xls*";
                    break % End the error checking loop
                end
                
                % Try loading the file into a table format, and display
                % error if it cannot be loaded.
                try
                    % Load file into table format
                    app.variables = readtable(app.filePath + "\" + app.fileName);
                    app.Label.Text = app.fileName + " loaded!";
                    errCheck = false; % Set error check to false
                    break
                catch ME
                    app.Label.Text = "File failed to load";
                    break % End the error checking loop
                end
    
            end
         
            % If the file was sucessfully loaded (and err check was set to
            % false) then load the variables into the list boxes.
            if errCheck == false
                app.loadVariables()
            end
        end

        % Value changed function: ExperimentListBox
        function ExperimentListBoxValueChanged(app, event)
            % Any different variable selected in list box will update the
            % graph
            app.updateGraph();
        end

        % Value changed function: VariableListBox
        function VariableListBoxValueChanged(app, event)
            % Any different variable selected in list box will update the
            % graph
            app.updateGraph();
        end

        % Value changed function: ToggledebugCheckBox
        function ToggledebugCheckBoxValueChanged(app, event)
            % If the debug box is toggled then update the graph
            app.updateGraph();
        end

        % Value changed function: ColourpalletDropDown
        function ColourpalletDropDownValueChanged(app, event)
            app.colorScheme  = string(app.ColourpalletDropDown.Value);
            app.updateGraph();
        end

        % Value changed function: DefaultcolorDropDown
        function DefaultcolorDropDownValueChanged(app, event)
            app.defaultColor = string(app.DefaultcolorDropDown.Value);
            app.updateGraph();
        end

        % Value changed function: LinestyleDropDown
        function LinestyleDropDownValueChanged(app, event)
            app.lineStyle = string(app.LinestyleDropDown.Value);
            app.updateGraph();
        end

        % Value changed function: InvertToggle
        function InvertToggleValueChanged(app, event)
            app.updateGraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DesignspaceviewerLabel = uilabel(app.UIFigure);
            app.DesignspaceviewerLabel.Text = 'Design space viewer';
            app.VariableListBox = uilistbox(app.UIFigure);
            app.VariableListBox.Items = {};
            app.VariableListBox.Multiselect = 'on';
            app.VariableListBox.ValueChangedFcn = createCallbackFcn(app, @VariableListBoxValueChanged, true);
            app.VariableListBox.Value = {};
            app.ExperimentListBox = uilistbox(app.UIFigure);
            app.ExperimentListBox.Items = {};
            app.ExperimentListBox.Multiselect = 'on';
            app.ExperimentListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentListBoxValueChanged, true);
            app.ExperimentListBox.Value = {};
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.VariableLabel = uilabel(app.UIFigure);
            app.VariableLabel.Text = 'Variable';
            app.LoadfileButton = uibutton(app.UIFigure, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ToggledebugCheckBox = uicheckbox(app.UIFigure);
            app.ToggledebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggledebugCheckBoxValueChanged, true);
            app.ToggledebugCheckBox.Text = 'Debug mode';
            app.ColourpalletDropDownLabel = uilabel(app.UIFigure);
            app.ColourpalletDropDownLabel.Text = 'Colour pallet';
            app.ColourpalletDropDown = uidropdown(app.UIFigure);
            app.ColourpalletDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourpalletDropDownValueChanged, true);
            app.DefaultcolorDropDownLabel = uilabel(app.UIFigure);
            app.DefaultcolorDropDownLabel.Text = 'Default color';
            app.DefaultcolorDropDown = uidropdown(app.UIFigure);
            app.DefaultcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @DefaultcolorDropDownValueChanged, true);
            app.LinestyleDropDownLabel = uilabel(app.UIFigure);
            app.LinestyleDropDownLabel.Text = 'Line style';
            app.LinestyleDropDown = uidropdown(app.UIFigure);
            app.LinestyleDropDown.ValueChangedFcn = createCallbackFcn(app, @LinestyleDropDownValueChanged, true);
            app.InvertToggle = uicheckbox(app.UIFigure);
            app.InvertToggle.ValueChangedFcn = createCallbackFcn(app, @InvertToggleValueChanged, true);
            app.InvertToggle.Text = 'Invert colour';
            app.GraphAxes = uiaxes(app.UIFigure);
            xlabel(app.GraphAxes, 'X')
            ylabel(app.GraphAxes, 'Y')
            zlabel(app.GraphAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignSpace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end